classdef Bloch_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        NumberofspinsEditField       matlab.ui.control.NumericEditField
        NumberofspinsEditFieldLabel  matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        StatusLamp                   matlab.ui.control.Lamp
        ResetButton                  matlab.ui.control.Button
        RunSimulationButton          matlab.ui.control.Button
        PulseDropDown                matlab.ui.control.DropDown
        PulseDropDownLabel           matlab.ui.control.Label
        TREditField                  matlab.ui.control.NumericEditField
        TREditFieldLabel             matlab.ui.control.Label
        FlipAngleEditField           matlab.ui.control.NumericEditField
        FlipAngleEditFieldLabel      matlab.ui.control.Label
        NumberofRFPulsesEditField    matlab.ui.control.NumericEditField
        NumberofRFPulsesLabel        matlab.ui.control.Label
        T2EditField                  matlab.ui.control.NumericEditField
        T2EditFieldLabel             matlab.ui.control.Label
        T1EditField                  matlab.ui.control.NumericEditField
        T1EditFieldLabel             matlab.ui.control.Label
        CenterPanel                  matlab.ui.container.Panel
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        LogTab                       matlab.ui.container.Tab
        TextArea                     matlab.ui.control.TextArea
        TimeSlider                   matlab.ui.control.Slider
        TimeSliderLabel              matlab.ui.control.Label
        RunPauseButton               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        T1; % 50*TR T1-realxation time
        T2; % 8*TR T2-decay time
        TR; % 4.0
        nspin; % 205
        vAlpha % Flip angles in degrees
        nFlipSteps = 20;
        nDephSteps = 80;
        np; % 2 Number of RF pulses 
        mxv;
        myv;
        mzv;
        mxyMag;
        numPts;
        dp;
        stepCounter = 1;
        StopRequested = false; % For stopping the simulation
        StateStorage % This will store the simulation states
        SimulationRunning = false; % Track whether the simulation is currently running
        CurrentStep = 1;          % Track the current step of the simulation

        TissueSphere % Sphere data for the tissue
        TissueT1 % T1 value for the tissue
        TissueT2 % T2 value for the tissue
        TissueSphereCreated = false; % Flag to track if the sphere is created
        TissueTypes; % Structure to store T1 and T2 values for different tissue types
    end

    methods (Access = private)
        function logMessage(app, message, type)
            if isempty(app.TextArea) || ~isa(app.TextArea, 'matlab.ui.control.TextArea')
                error('LogTextArea is not initialized or not a TextArea');
            end

            timestamp = datestr(now, 'yyyy-mm-dd HH:MM:SS');
            formattedMessage = sprintf('%s - [%s] %s\n', timestamp, upper(type), message);
            if isempty(app.TextArea.Value)
                app.TextArea.Value = {formattedMessage};
            else
                app.TextArea.Value = [app.TextArea.Value; {formattedMessage}];
            end
            drawnow; % Refresh UI and ensure the last message is visible
            app.TextArea.scroll('bottom'); % Scroll to the bottom if supported
        end

        function [mx,my,mz] = dephase(~,dp,mx,my,mz)

            nx = mx;
            ny = my;
            nz = mz;

            mx = cos(dp).*nx - sin(dp).*ny;      % dephasing
            my = sin(dp).*nx + cos(dp).*ny;
            mz = nz;

        end

        function [mx,my,mz] = excite(~,fa,mx,my,mz,flip)
      
             nx = mx;
             ny = my;
             nz = mz;
        
             
             if flip == 1
                 
               mx = nx;
               my = cos(fa)*ny - sin(fa)*nz;      % rf - excitation
               mz = sin(fa)*ny + cos(fa)*nz;
             
            else
               
               mx = cos(fa)*nx - sin(fa)*nz;
               my = my;      % rf - excitation
               mz = sin(fa)*nx + cos(fa)*nz;
             
             end
        end

        function [mx,my,mz] = relax(~,e1,e2,mx,my,mz)

            mx = mx.*e2;
            my = my.*e2;
            mz = 1.0+e1*(mz-1.0); % T1 relaxation
        end

        function plot3d_mxy(app, mxv, myv, mzv, kk, mxyMag, numPts, nFlip, nDeph)
            % Clear previous plots
            cla(app.UIAxes2);  % Clears all graphics objects from UIAxes2
%             cla(app.UIAxes3);  % Clear the previous plot on UIAxes3
            app.UIAxes.Color = [0.2 0.2 0.3];    % Dark color for UIAxes
            app.UIAxes2.Color = [0.2 0.2 0.3];   % Dark color for UIAxes2
            app.UIAxes_2.Color = [0.2 0.2 0.3];  % Dark color for UIAxes_2
%             app.UIAxes3.Color = [0.2 0.2 0.3];    % Dark color for UIAxes3

            % First 3D plot for magnetization vectors
            hold(app.UIAxes2, 'on');
            app.UIAxes2.BackgroundColor = [0.2 0.2 0.3]; % This sets it to a light gray color
            quiver3(app.UIAxes2, 0, 0, 0, mxv(201), myv(201), mzv(201), 0, 'Color', 'r', 'LineWidth', 3, 'MarkerSize', 6);
            quiver3(app.UIAxes2, 0, 0, 0, mxv(202), myv(202), mzv(202), 0, 'Color', 'm', 'LineWidth', 3, 'MarkerSize', 6);
            quiver3(app.UIAxes2, 0, 0, 0, mxv(204), myv(204), mzv(204), 0, 'Color', 'w', 'LineWidth', 3, 'MarkerSize', 6);
            quiver3(app.UIAxes2, 0, 0, 0, mxv(205), myv(205), mzv(205), 0, 'Color', 'b', 'LineWidth', 3, 'MarkerSize', 6);
            quiver3(app.UIAxes2, 0, 0, 0, mxv(203), myv(203), mzv(203), 0, 'Color', 'y', 'LineWidth', 3, 'MarkerSize', 6);

            axis(app.UIAxes2, 'equal');
            line(app.UIAxes2, [0 0], [0 0], [-1 1], 'Color', 'w', 'LineWidth', 1);
            line(app.UIAxes2, [0 0], [-1 1], [0 0], 'Color', 'w', 'LineWidth', 1);
            line(app.UIAxes2, [-1 1], [0 0], [0 0], 'Color', 'w', 'LineWidth', 1);
            text(app.UIAxes2, -1.15, 0, 0, 'M_x', 'FontSize', 12, 'FontAngle', 'italic', 'Color', 'w');
            text(app.UIAxes2, 0, 1.3, 0, 'M_y', 'FontSize', 12, 'FontAngle', 'italic', 'Color', 'w');
            text(app.UIAxes2, 0, 0, 1.3, 'M_z', 'FontSize', 12, 'FontAngle', 'italic', 'Color', 'w');
            view(app.UIAxes2, -135, 30);
            hold(app.UIAxes2, 'off');

            % Plotting magnitude on the second axes
            cla(app.UIAxes);  % Clear the previous plot on UIAxes
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, mxyMag(1:kk), 'y', 'LineWidth', 2);
            set(app.UIAxes, 'YLim', [0 1], 'YTick', [0 1]);
            set(app.UIAxes, 'XLim', [1 numPts], 'XTick', 2*(nFlip + nDeph));
            ylabel(app.UIAxes, 'Signal [a.u.]', 'FontSize', 10.5);
            hold(app.UIAxes, 'off');

            % Pulse sequence diagram on the third axes
            cla(app.UIAxes_2);  % Clear the previous plot on UIAxes_2
            hold(app.UIAxes_2, 'on');
            line(app.UIAxes_2, [1 numPts], [-1 -1], 'Color', 'w'); % Gradient baseline
            line(app.UIAxes_2, [1 numPts], [0.5 0.5], 'Color', 'w'); % RF baseline

            % Generate RF coordinates and plot each pulse separately
            startCoord = 1;
            baseInterval = app.nFlipSteps + app.nDephSteps;
            for i = 1:app.np
                pulseLength = nFlip;
                rfc = ((1:pulseLength) - pulseLength / 2 - 0.5) / 2;
                rfp = sinc(rfc);

                currentRFCoord = startCoord:(startCoord + pulseLength - 1);
                currentRFPlot = 0.5 + (1.3 * rfp);

                plot(app.UIAxes_2, currentRFCoord, currentRFPlot, 'y', 'LineWidth', 1);

                % Add text labels for the pulses
                text(app.UIAxes_2, startCoord + pulseLength / 2 - 1, 1.3, sprintf('%d^o', app.vAlpha(i)), 'Color', 'w', 'FontSize', 10);

                % Update start coordinate for the next pulse
%                 startCoord = startCoord + pulseLength + nDeph;
                if app.vAlpha(i) == 180
                    startCoord = startCoord + baseInterval + app.nDephSteps;  % Adjust for 180° pulse
                else
                    startCoord = startCoord + baseInterval;
                end
            end

            % Plot gradients
            startGrad = nFlip + 10;
            gradCoord1 = [startGrad, startGrad + 5, startGrad + (5 + nDeph - 30), startGrad + (5 + nDeph - 30) + 5];
            gradPlot1 = [-1, -0.3, -0.3, -1];

            startGrad = 2 * nFlip + 2 * nDeph - (nDeph - 30) - 5;
            gradCoord2 = [startGrad, startGrad + 5, startGrad + 5 + 2 * (nDeph - 30), startGrad + 5 + 2 * (nDeph - 30) + 5];
            gradPlot2 = [-1, -0.3, -0.3, -1];

            plot(app.UIAxes_2, gradCoord1, gradPlot1, 'w', 'LineWidth', 2);
            plot(app.UIAxes_2, gradCoord2, gradPlot2, 'w', 'LineWidth', 2);

            % Add the "Readout" label
            text(app.UIAxes_2, mean(gradCoord2), -1.5, 'Readout', 'Color', 'w', 'FontSize', 15, 'HorizontalAlignment', 'center');

            % Adjust the axes limits and labels
            set(app.UIAxes_2, 'YLim', [-2 2], 'YTick', [-1 0.5], 'YTickLabel', {'Grad'; 'RF'});
            set(app.UIAxes_2, 'XLim', [1 numPts], 'XTick', 2 * (nFlip + nDeph), 'XTickLabel', {'TE'});
            line(app.UIAxes_2, [kk kk], [-2 2], 'LineWidth', 2, 'Color', 'r');
            hold(app.UIAxes_2, 'off');

%             % Plot Mz and Mxy against time
%             time = 1:kk;
%             plot(app.UIAxes3, time, mzv(1:kk), 'b', 'LineWidth', 2); % Plot Mz in blue
%             hold(app.UIAxes3, 'on');
%             plot(app.UIAxes3, time, mxyMag(1:kk), 'r', 'LineWidth', 2); % Plot Mxy in red
%             hold(app.UIAxes3, 'off');
%             set(app.UIAxes3, 'XLim', [1 numPts], 'YLim', [0 1]);
%             xlabel(app.UIAxes3, 'No. of TR periods');
%             ylabel(app.UIAxes3, 'Magnetization');
%             legend(app.UIAxes3, {'M_z', 'M_{xy}'}, 'Location', 'northeast');

            drawnow;
        end

        function RFLoop(app, dp, vAlpha, nspin, nFlipSteps, mxv, myv, mzv, ...
                stepCounter, mxyMag, numPts, nDephSteps)

            for k=1:app.np %  RF pulse loop

                % --------------------------------------------------------------------
                % RF - excitation
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                fa = vAlpha(k) * pi/(180.0);
                n  = 1:nspin;
                fa = fa/nFlipSteps;

                for l = 1:nFlipSteps
                    if app.StopRequested
                        app.StopRequested = false;  % Reset the flag
                        app.StatusLamp.Color = 'red';  % Indicate stopped
                        break;  % Exit the loop
                    end

                    [mxv(n),myv(n),mzv(n)] = app.excite(fa,mxv(n),myv(n),mzv(n),k);         % excitation

                    stepCounter = stepCounter + 1;
                    mxyMag(stepCounter) = sqrt(sum(mxv)^2 + sum(myv)^2)/nspin;       % mag sum
                    app.plot3d_mxy(mxv,myv,mzv,stepCounter,mxyMag,numPts,nFlipSteps,nDephSteps)
                    app.StateStorage(stepCounter).mxv = mxv;
                    app.StateStorage(stepCounter).myv = myv;
                    app.StateStorage(stepCounter).mzv = mzv;
                    app.StateStorage(stepCounter).mxyMag = mxyMag;
                end


                % --------------------------------------------------------------------
                % Relaxation and dephasing
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                dp2 = dp*1/nDephSteps;       % Dephasing increment per dephasing step
                e1 = exp(-app.TR/nDephSteps/app.T1); % T1-relaxation increment per dephasing step
                e2 = exp(-app.TR/nDephSteps/app.T2); % T2-decay increment per dephasing step

                for l = 1:k*nDephSteps
                    if app.StopRequested
                        app.StopRequested = false;  % Reset the flag
                        app.StatusLamp.Color = 'red';  % Indicate stopped
                        break;  % Exit the loop
                    end
                    
                    if k == 1     % 1st rf-pulse, prewinder gradient
                        startGrad = 15;
                        gradLen   = nDephSteps - 30;
                    elseif k == 2 % 2nd rf-pulse, readout gradient
                        startGrad = 30;
                        gradLen   = 2 *(nDephSteps - 30);
                    end

                    if l > startGrad && l < startGrad + gradLen
                        [mxv(n),myv(n),mzv(n)] = app.dephase(dp2*3,mxv(n),myv(n),mzv(n));    % dephasing
                    end
                    [mxv(n),myv(n),mzv(n)] = app.dephase(dp2,mxv(n),myv(n),mzv(n));          % dephasing
                    [mxv(n), myv(n), mzv(n)] = app.relax(e1, e2, mxv(n), myv(n), mzv(n));    % relaxation

                    stepCounter           = stepCounter + 1;
                    mxyMag(stepCounter)   = sqrt(sum(mxv)^2 + sum(myv)^2)/nspin;         % mag sum
                    app.plot3d_mxy(mxv,myv,mzv,stepCounter,mxyMag,numPts,nFlipSteps,nDephSteps)
                    app.StateStorage(stepCounter).mxv = mxv;
                    app.StateStorage(stepCounter).myv = myv;
                    app.StateStorage(stepCounter).mzv = mzv;
                    app.StateStorage(stepCounter).mxyMag = mxyMag;
                end % end dephasing
            end
        end

        function runSimulationFromCurrentStep(app)
            while app.CurrentStep <= app.numPts && app.SimulationRunning
                state = app.StateStorage(app.CurrentStep);
                app.plot3d_mxy(state.mxv, state.myv, state.mzv, app.CurrentStep, state.mxyMag, app.numPts, app.nFlipSteps, app.nDephSteps);
                app.CurrentStep = app.CurrentStep + 1; % Move to the next step
                app.TimeSlider.Value = app.CurrentStep; % Update slider position
                pause(0.05); % Adjust timing as needed
            end
            if app.CurrentStep > app.numPts
                app.SimulationRunning = false; % Stop if end is reached
                app.StartPauseButton.Text = 'Run';
            end
        end

        % Update flip angles based on user input
        function updateFlipAngle(app)
            pulseIndex = str2double(regexp(app.PulseDropDown.Value, '\d+', 'match')); % Get the selected pulse index
            if ~isempty(pulseIndex) && pulseIndex <= length(app.vAlpha)
                app.vAlpha(pulseIndex) = app.FlipAngleEditField.Value; % Update the flip angle
            end
        end


        function updatePulseDropdown(app)
            % Update the dropdown list based on the number of pulses (np)
            items = arrayfun(@(x) sprintf('Pulse %d', x), 1:app.np, 'UniformOutput', false);
            app.PulseDropDown.Items = items;
            if ~isempty(items)
                app.PulseDropDown.Value = items{1}; % Set the default value if items are not empty
                app.FlipAngleEditField.Value = app.vAlpha(1); % Set the default flip angle
            else
                app.PulseDropDown.Value = ''; % No items to select
            end
        end

        function calculateTotalPoints(app)
            basePtsPerPulse = app.nFlipSteps + app.nDephSteps;  % base points per pulse
            additionalPts = 0;  % Initialize additional points

            for i = 1:app.np
                if app.vAlpha(i) == 180
                    additionalPts = additionalPts + app.nDephSteps;  % Add extra points for 180° pulse
                end
            end

            app.numPts = app.np * basePtsPerPulse + additionalPts;  % Update total points
        end

        function startCoord = calculateStartCoordForPulse(app, pulseIndex)
            startCoord = 1;  % Start from the first point for the first pulse
            baseInterval = app.nFlipSteps + app.nDephSteps;  % Normal interval between pulses

            for i = 1:(pulseIndex - 1)
                if app.vAlpha(i) == 180
                    startCoord = startCoord + baseInterval + app.nDephSteps;  % Adjust for 180° pulse
                else
                    startCoord = startCoord + baseInterval;
                end
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.T1 = app.T1EditField.Value;
            app.T2 = app.T2EditField.Value;
            app.TR = app.TREditField.Value;
            app.nspin = app.NumberofspinsEditField.Value;
%             app.nspin = 205;
            app.np = app.NumberofRFPulsesEditField.Value;
            app.vAlpha = 90 * ones(1, app.np); % Initialize flip angles to 90 degrees for each pulse

            updatePulseDropdown(app);

            % Log a message to indicate successful initialization
            app.logMessage('LogTextArea is initialized!', 'info');
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            
            try
                app.StatusLamp.Color = 'green';  % Indicate processing
                % --------------------------------------------------------------------
                % Bloch simulation of Spin Echo magnatization
                % ----------------------------------------------------

                % --------------------------------------------------------------------
                % set up initial configuration (mx = mx = 0, mz = 1)
                % --------------------------------------------------------------------
                app.mxv(1:app.nspin) = 0.0;
                app.myv(1:app.nspin) = 0.0;
                app.mzv(1:app.nspin) = 1.0;

                app.mxyMag(1:app.np*(app.nFlipSteps+app.nDephSteps)) = 0;
%                 app.numPts = app.np*(app.nFlipSteps + app.nDephSteps) + app.nDephSteps;
                calculateTotalPoints(app)

                % dephasing distribution
                app.dp          = randn(1,app.nspin-5)*pi;
                app.dp(app.nspin-4:app.nspin) = [-0.65 -0.35 0 0.25 0.85]*pi;
%                 app.dp(201:205) = [0 0.25 0.75 1 1.25]*pi;

                %             stepCounter = 1;
                app.plot3d_mxy(app.mxv,app.myv,app.mzv,app.stepCounter,app.mxyMag,app.numPts,app.nFlipSteps,app.nDephSteps)

                %  RF pulse loop
                app.RFLoop(app.dp, app.vAlpha, app.nspin, app.nFlipSteps, app.mxv, app.myv, app.mzv, ...
                    app.stepCounter, app.mxyMag, app.numPts, app.nDephSteps)


                if ~app.StopRequested
                    app.StatusLamp.Color = 'blue';  % Indicate completion
                end

            catch ME  % ME is an MException object containing the error details
                app.logMessage(ME.message, 'error');  % Log the error message
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Clear the existing plots
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes_2);
            app.StopRequested = false;

            startupFcn(app)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopRequested = true;
        end

        % Value changed function: T2EditField
        function T2EditFieldValueChanged(app, event)
            app.T2 = app.T2EditField.Value;
        end

        % Value changed function: T1EditField
        function T1EditFieldValueChanged(app, event)
            app.T1 = app.T1EditField.Value;
        end

        % Value changed function: TREditField
        function TREditFieldValueChanged(app, event)
            app.TR = app.TREditField.Value;
        end

        % Callback function
        function NumberofspinsEditFieldValueChanged(app, event)
            app.nspin = app.NumberofspinsEditField.Value;
        end

        % Value changed function: NumberofRFPulsesEditField
        function NumberofRFPulsesEditFieldValueChanged(app, event)
            app.np = app.NumberofRFPulsesEditField.Value;
            if length(app.vAlpha) < app.np
                app.vAlpha(end+1:app.np) = 90; % Initialize new pulses to 90 degrees
            elseif length(app.vAlpha) > app.np
                app.vAlpha = app.vAlpha(1:app.np); % Trim to the new number of pulses
            end
            updatePulseDropdown(app);
        end

        % Drop down opening function: PulseDropDown
        function PulseDropDownOpening(app, event)
            % Update the dropdown list based on the number of pulses (np)
            updatePulseDropdown(app);
        end

        % Value changed function: PulseDropDown
        function PulseDropDownValueChanged(app, event)
            pulseIndex = str2double(regexp(app.PulseDropDown.Value, '\d+', 'match')); % Get the selected pulse index
            if ~isempty(pulseIndex) && pulseIndex <= length(app.vAlpha)
                app.FlipAngleEditField.Value = app.vAlpha(pulseIndex);
            end
        end

        % Value changed function: FlipAngleEditField
        function FlipAngleEditFieldValueChanged(app, event)
            updateFlipAngle(app);
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            try
                app.CurrentStep = round(app.TimeSlider.Value); % Update current step
                state = app.StateStorage(app.CurrentStep);     % Retrieve saved state
                % Update the plots using the state
                app.plot3d_mxy(state.mxv, state.myv, state.mzv, app.CurrentStep, state.mxyMag, app.numPts, app.nFlipSteps, app.nDephSteps);
            catch ME
                app.logMessage(ME.message, 'error');  % Log the error message
            end
        end

        % Button pushed function: RunPauseButton
        function RunPauseButtonPushed(app, event)
            if app.SimulationRunning
                app.SimulationRunning = false; % Pause the simulation
                app.RunPauseButton.Text = 'Run';
            else
                app.SimulationRunning = true;  % Start or resume the simulation
                app.RunPauseButton.Text = 'Pause';
                app.runSimulationFromCurrentStep(); % Call a method to run the simulation
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {178, '1x', 225};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.T1EditFieldLabel = uilabel(app.LeftPanel);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.T1EditField.ValueDisplayFormat = '%.0f ms';
            app.T1EditField.ValueChangedFcn = createCallbackFcn(app, @T1EditFieldValueChanged, true);
            app.T1EditField.Value = 200;
            app.T2EditFieldLabel = uilabel(app.LeftPanel);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.T2EditField.ValueDisplayFormat = '%.0f ms';
            app.T2EditField.ValueChangedFcn = createCallbackFcn(app, @T2EditFieldValueChanged, true);
            app.T2EditField.Value = 32;
            app.NumberofRFPulsesLabel = uilabel(app.LeftPanel);
            app.NumberofRFPulsesLabel.Text = 'Number of RF-Pulses';
            app.NumberofRFPulsesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofRFPulsesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofRFPulsesEditFieldValueChanged, true);
            app.NumberofRFPulsesEditField.Value = 2;
            app.FlipAngleEditFieldLabel = uilabel(app.LeftPanel);
            app.FlipAngleEditFieldLabel.Text = 'Flip Angle';
            app.FlipAngleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FlipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @FlipAngleEditFieldValueChanged, true);
            app.TREditFieldLabel = uilabel(app.LeftPanel);
            app.TREditFieldLabel.Text = 'TR';
            app.TREditField = uieditfield(app.LeftPanel, 'numeric');
            app.TREditField.ValueDisplayFormat = '%.0f ms';
            app.TREditField.ValueChangedFcn = createCallbackFcn(app, @TREditFieldValueChanged, true);
            app.TREditField.Value = 4;
            app.PulseDropDownLabel = uilabel(app.LeftPanel);
            app.PulseDropDownLabel.Text = 'Pulse #';
            app.PulseDropDown = uidropdown(app.LeftPanel);
            app.PulseDropDown.Items = {'Pulse 1', 'Pulse 2'};
            app.PulseDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PulseDropDownOpening, true);
            app.PulseDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseDropDownValueChanged, true);
            app.PulseDropDown.Value = 'Pulse 1';
            app.RunSimulationButton = uibutton(app.LeftPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StatusLamp = uilamp(app.LeftPanel);
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.NumberofspinsEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofspinsEditFieldLabel.Text = 'Number of spins';
            app.NumberofspinsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofspinsEditField.Value = 205;
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Pulse Sequence')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, '3D visualization of magnetization vectors')
            xlabel(app.UIAxes2, 'M_x')
            ylabel(app.UIAxes2, 'M_y')
            zlabel(app.UIAxes2, 'M_z')
            app.UIAxes2.XLim = [-1 1];
            app.UIAxes2.YLim = [-1 1];
            app.UIAxes2.ZLim = [-1 1];
            app.UIAxes2.ZTick = [-1 0 1];
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes_2, 'Time')
            app.RightPanel = uipanel(app.GridLayout);
            app.RunPauseButton = uibutton(app.RightPanel, 'push');
            app.RunPauseButton.ButtonPushedFcn = createCallbackFcn(app, @RunPauseButtonPushed, true);
            app.RunPauseButton.Text = 'Run/Pause';
            app.TimeSliderLabel = uilabel(app.RightPanel);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.RightPanel);
            app.TimeSlider.Limits = [0 300];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.TextArea = uitextarea(app.LogTab);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bloch_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end